classdef Double_pendulum_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        IntroductionTab                matlab.ui.container.Tab
        LetsStartButton                matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        EditField_5                    matlab.ui.control.EditField
        HomeTab                        matlab.ui.container.Tab
        TimeSlider                     matlab.ui.control.Slider
        TimeSliderLabel                matlab.ui.control.Label
        Gravitationalaccgms2EditField  matlab.ui.control.NumericEditField
        Gravitationalaccgms2EditFieldLabel  matlab.ui.control.Label
        Massm1kgEditField              matlab.ui.control.NumericEditField
        Massm1kgEditFieldLabel         matlab.ui.control.Label
        Omega2radsEditFieldLabel       matlab.ui.control.Label
        Omega2radsEditField            matlab.ui.control.NumericEditField
        Omega1radsEditField            matlab.ui.control.NumericEditField
        Omega1radsEditFieldLabel       matlab.ui.control.Label
        Theta2radEditField             matlab.ui.control.NumericEditField
        Theta2radEditFieldLabel        matlab.ui.control.Label
        Theta1radEditField             matlab.ui.control.NumericEditField
        Theta1radEditFieldLabel        matlab.ui.control.Label
        Massm2kgEditField              matlab.ui.control.NumericEditField
        Massm2kgEditFieldLabel         matlab.ui.control.Label
        LenghtL2mEditField             matlab.ui.control.NumericEditField
        LenghtL2mEditFieldLabel        matlab.ui.control.Label
        LenghtL1mEditField             matlab.ui.control.NumericEditField
        LenghtL1mEditFieldLabel        matlab.ui.control.Label
        StartAnalysisButton            matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        EditField_2                    matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        AnalysisTab                    matlab.ui.container.Tab
        ShowEnergyButton               matlab.ui.control.Button
        BackButton_4                   matlab.ui.control.Button
        NextTabButton                  matlab.ui.control.Button
        ShowAngularVelocityButton      matlab.ui.control.Button
        ShowPositionButton             matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        Analysis2Tab                   matlab.ui.container.Tab
        BackButton_5                   matlab.ui.control.Button
        NextTabButton_2                matlab.ui.control.Button
        ShowAngularPositionButton      matlab.ui.control.Button
        ShowCompleteTrajectoryButton   matlab.ui.control.Button
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        CompareTab                     matlab.ui.container.Tab
        BackButton_3                   matlab.ui.control.Button
        EditField_4                    matlab.ui.control.EditField
        Gravititatinalaccgms2EditField_5  matlab.ui.control.NumericEditField
        Gravititatinalaccgms2EditField_5Label  matlab.ui.control.Label
        Omega2radsEditField_5          matlab.ui.control.NumericEditField
        Omega2radsEditField_5Label     matlab.ui.control.Label
        Theta2radEditField_5           matlab.ui.control.NumericEditField
        Theta2radEditField_5Label      matlab.ui.control.Label
        LenghtL2mEditField_5           matlab.ui.control.NumericEditField
        LenghtL2mEditField_5Label      matlab.ui.control.Label
        Massm2kgEditField_5            matlab.ui.control.NumericEditField
        Massm2kgEditField_5Label       matlab.ui.control.Label
        Omega1radsEditField_5          matlab.ui.control.NumericEditField
        Omega1radsEditField_5Label     matlab.ui.control.Label
        Theta1radEditField_5           matlab.ui.control.NumericEditField
        Theta1radEditField_5Label      matlab.ui.control.Label
        LenghtL1mEditField_5           matlab.ui.control.NumericEditField
        LenghtL1mEditField_5Label      matlab.ui.control.Label
        Massm1kgEditField_5            matlab.ui.control.NumericEditField
        Massm1kgEditField_5Label       matlab.ui.control.Label
        TimeSlider_2                   matlab.ui.control.Slider
        TimeSlider_2Label              matlab.ui.control.Label
        Gravitationalaccgms2EditField_2  matlab.ui.control.NumericEditField
        Gravitationalaccgms2EditField_2Label  matlab.ui.control.Label
        Omega2radsEditField_4          matlab.ui.control.NumericEditField
        Omega2radsEditField_4Label     matlab.ui.control.Label
        Theta2radEditField_4           matlab.ui.control.NumericEditField
        Theta2radEditField_4Label      matlab.ui.control.Label
        LenghtL2mEditField_4           matlab.ui.control.NumericEditField
        LenghtL2mEditField_4Label      matlab.ui.control.Label
        Massm2kgEditField_4            matlab.ui.control.NumericEditField
        Massm2kgEditField_4Label       matlab.ui.control.Label
        Omega1radsEditField_4          matlab.ui.control.NumericEditField
        Omega1radsEditField_4Label     matlab.ui.control.Label
        Theta1radEditField_4           matlab.ui.control.NumericEditField
        Theta1radEditField_4Label      matlab.ui.control.Label
        LenghtL1mEditField_4           matlab.ui.control.NumericEditField
        LenghtL1mEditField_4Label      matlab.ui.control.Label
        Massm1kgEditField_4            matlab.ui.control.NumericEditField
        Massm1kgEditField_4Label       matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        CompareButton                  matlab.ui.control.Button
        DuplicateCurrentSysteminSystem1Button  matlab.ui.control.Button
        ComparisonAnalysisTab          matlab.ui.container.Tab
        BackButton_2                   matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        CompareAngularVelocityButton   matlab.ui.control.Button
        ComparePostionButton           matlab.ui.control.Button
        UIAxes6_6                      matlab.ui.control.UIAxes
        UIAxes6_3                      matlab.ui.control.UIAxes
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        ComparisonAnalysis2Tab         matlab.ui.container.Tab
        BackButton_7                   matlab.ui.control.Button
        NextButton_2                   matlab.ui.control.Button
        CompareAngularPositionButton   matlab.ui.control.Button
        CompareEnergyButton            matlab.ui.control.Button
        UIAxes6_10                     matlab.ui.control.UIAxes
        UIAxes10_2                     matlab.ui.control.UIAxes
        UIAxes6_7                      matlab.ui.control.UIAxes
        UIAxes10                       matlab.ui.control.UIAxes
        ComparisonAnalysis3Tab         matlab.ui.container.Tab
        BackButton_6                   matlab.ui.control.Button
        CompareTrajectoryButton        matlab.ui.control.Button
        UIAxes6_9                      matlab.ui.control.UIAxes
        UIAxes6_8                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         check=1;% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AnalysisTab, StartAnalysisButton
        function StartButtonPushed(app, event)
            m1=app.Massm1kgEditField.Value;
            m2=app.Massm2kgEditField.Value;
            l1=app.LenghtL2mEditField.Value;
            l2=app.LenghtL2mEditField.Value;
            g=app.Gravitationalaccgms2EditField.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            % intial_conditions

            theta1=app.Theta1radEditField.Value;
            theta2=app.Theta2radEditField.Value;
            d_theta1=app.Omega1radsEditField.Value;
            d_theta2=app.Omega2radsEditField.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);


            a=results(:,1);
            b=results(:,2);
            app.TabGroup.SelectedTab=app.AnalysisTab;
        end

        % Button down function: AnalysisTab
        function AnalysisTabButtonDown(app, event)
            
        end

        % Button pushed function: ShowPositionButton
        function ShowPositionButtonPushed(app, event)
            m1=app.Massm1kgEditField.Value;
            m2=app.Massm2kgEditField.Value;
            l1=app.LenghtL1mEditField.Value;
            l2=app.LenghtL2mEditField.Value;
            g=app.Gravitationalaccgms2EditField.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            theta1=app.Theta1radEditField.Value;
            theta2=app.Theta2radEditField.Value;
            d_theta1=app.Omega1radsEditField.Value;
            d_theta2=app.Omega2radsEditField.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);


            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);


             for i=1:length(t)
                cla(app.UIAxes5)
                x0=0;
                y0=0; 
                plot(app.UIAxes5,x1(1),y1(1),'ro','MarkerFaceColor','r');
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,x2(1),y2(1),'go','MarkerFaceColor','g');
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,[-1 1],[0 0],'color','w')
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,[x0 x1(i)],[y0 y1(i)],'color','w')
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,[x1(i) x2(i)],[y1(i) y2(i)],'color','w')
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,x1(i),y1(i),'ro','MarkerFaceColor','r');
                plot(app.UIAxes5,x2(i),y2(i),'go','MarkerFaceColor','g');
                plot(app.UIAxes5, x1(1:i) , y1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-  -");
                plot(app.UIAxes5,x2(1:i) , y2(1:i) , LineWidth=1 , Color='g' ,  LineStyle="-  -");
                axis(app.UIAxes5,'equal',[-l1-l2-1 l1+l2+1 -l1-l2-1 l1+l2+1]) 
                pause(0.001)
                
             end
            
        end

        % Button pushed function: ShowAngularVelocityButton
        function ShowAngularVelocityButtonPushed(app, event)
            m1=app.Massm1kgEditField.Value;
            m2=app.Massm2kgEditField.Value;
            l1=app.LenghtL1mEditField.Value;
            l2=app.LenghtL2mEditField.Value;
            g=app.Gravitationalaccgms2EditField.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            % intial_conditions

            theta1=app.Theta1radEditField.Value;
            theta2=app.Theta2radEditField.Value;
            d_theta1=app.Omega1radsEditField.Value;
            d_theta2=app.Omega2radsEditField.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);

            omega1=results(:,3);
            omega2=results(:,4);
            max_omega1=max(omega1);
            max_omega2=max(omega2);
            min_omega1=min(omega1);
            min_omega2=min(omega2);
            if(max_omega1>=max_omega2)
                max_omega2=max_omega1;
            end
            if(min_omega1<=min_omega2)
                min_omega2=min_omega1;
            end

             for i=1:length(t)
                cla(app.UIAxes2)
                plot(app.UIAxes2,t(i),omega1(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,t(i),omega2(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,t(1:i) , omega1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,t(1:i) , omega2(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes2,'on')
                axis(app.UIAxes2,[0 app.TimeSlider.Value,min_omega2-3 max_omega2+3 ])
                pause(0.0001)
             end
        end

        % Button pushed function: ShowEnergyButton
        function ShowEnergyButtonPushed(app, event)
            m1=app.Massm1kgEditField.Value;
            m2=app.Massm2kgEditField.Value;
            l1=app.LenghtL1mEditField.Value;
            l2=app.LenghtL2mEditField.Value;
            g=app.Gravitationalaccgms2EditField.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            % intial_conditions

            theta1=app.Theta1radEditField.Value;
            theta2=app.Theta2radEditField.Value;
            d_theta1=app.Omega1radsEditField.Value;
            d_theta2=app.Omega2radsEditField.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);
                
            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);
            omega1=results(:,3);
            omega2=results(:,4);
            PE1=(m1.*g.*y1);
            KE1=0.5.*m1.*l1.*l1.*omega1.*omega1;
            PE2=(m2.*g.*y2);
            KE2=0.5*m2.*((l1.*l1.*omega1.*omega1)+(l2.*l2.*omega2.*omega2)+(2.*l1.*l2.*omega1.*omega2.*cos(a-b)));
            
            E1=PE1+KE1;
            E2=PE2+KE2; 
            max_E1=max(E1);
            max_E2=max(E2);
            min_E1=min(E1);
            min_E2=min(E2);
            if(max_E1>=max_E2)
                max_E2=max_E1;
            end
            if(min_E1<=min_E2)
                min_E2=min_E1;
            end
             for i=1:length(t)
                cla(app.UIAxes4)
                plot(app.UIAxes4,t(i),E1(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes4,'on')
                plot(app.UIAxes4,t(i),E2(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes4,'on')
                plot(app.UIAxes4,t(1:i) , E1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes4,'on')
                plot(app.UIAxes4,t(1:i) , E2(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes4,'on')
                axis(app.UIAxes4,[0 app.TimeSlider.Value, min_E2-3 max_E2+3])
                pause(0.0001)
             end
        end

        % Button pushed function: ShowCompleteTrajectoryButton
        function ShowCompleteTrajectoryButtonPushed(app, event)
            m1=app.Massm1kgEditField.Value;
            m2=app.Massm2kgEditField.Value;
            l1=app.LenghtL1mEditField.Value;
            l2=app.LenghtL2mEditField.Value;
            g=app.Gravitationalaccgms2EditField.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            % intial_conditions

            theta1=app.Theta1radEditField.Value;
            theta2=app.Theta2radEditField.Value;
            d_theta1=app.Omega1radsEditField.Value;
            d_theta2=app.Omega2radsEditField.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);
                
            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);

            x0=0;
            y0=0;
            cla(app.UIAxes3)
            plot(app.UIAxes3,x1(1),y1(1),'ro','MarkerFaceColor','r');
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,x2(1),y2(1),'go','MarkerFaceColor','g');
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,0,0,'w.')
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,[-1 1],[0 0],'color','w')
            hold(app.UIAxes3,'on')  
            plot(app.UIAxes3,[x0 x1(end)],[y0 y1(end)],'color','w')
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,[x1(end) x2(end)],[y1(end) y2(end)],'color','w')
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3, x1(1:end) , y1(1:end) , LineWidth=1 , Color='r' ,  LineStyle="-  -");
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,x2(1:end) , y2(1:end) , LineWidth=1 , Color='g',  LineStyle="-  -");
            axis(app.UIAxes3,'equal',[-l1-l2-1 l1+l2+1 -l1-l2-1 l1+l2+1])
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,x1(end),y1(end),'ro','MarkerFaceColor','r');
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,x2(end),y2(end),'go','MarkerFaceColor','g');
            pause(0.001)

        end

        % Button pushed function: DuplicateCurrentSysteminSystem1Button
        function DuplicateCurrentSysteminSystem1ButtonPushed(app, event)
            app.Massm1kgEditField_4.Value=app.Massm1kgEditField.Value;
            app.Massm2kgEditField_4.Value=app.Massm2kgEditField.Value;
            app.LenghtL1mEditField_4.Value=app.LenghtL1mEditField.Value;
            app.LenghtL2mEditField_4.Value=app.LenghtL2mEditField.Value;
            app.Theta1radEditField_4.Value=app.Theta1radEditField.Value;
            app.Theta2radEditField_4.Value=app.Theta2radEditField.Value;
            app.Omega1radsEditField_4.Value=app.Omega1radsEditField.Value;
            app.Omega2radsEditField_4.Value=app.Omega2radsEditField.Value;
            app.Gravitationalaccgms2EditField_2.Value=app.Gravitationalaccgms2EditField.Value;
            app.TimeSlider_2.Value=app.TimeSlider.Value;
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ComparisonAnalysisTab;
        end

        % Button pushed function: NextTabButton
        function NextTabButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.Analysis2Tab;
        end

        % Button pushed function: ComparePostionButton
        function ComparePostionButtonPushed(app, event)
            m1=app.Massm1kgEditField_4.Value;
            m2=app.Massm2kgEditField_4.Value;
            l1=app.LenghtL1mEditField_4.Value;
            l2=app.LenghtL2mEditField_4.Value;
            g=app.Gravitationalaccgms2EditField_2.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            theta1=app.Theta1radEditField_4.Value;
            theta2=app.Theta2radEditField_4.Value;
            d_theta1=app.Omega1radsEditField_4.Value;
            d_theta2=app.Omega2radsEditField_4.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider_2.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);


            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);
            omega1=results(:,3);
            omega2=results(:,4);

            PE1=(m1.*g.*y1);
            KE1=0.5.*m1.*l1.*l1.*omega1.*omega1;
            PE2=(m2.*g.*y2);
            KE2=0.5*m2.*((l1.*l1.*omega1.*omega1)+(l2.*l2.*omega2.*omega2)+(2.*l1.*l2.*omega1.*omega2.*cos(a-b)));
            
            E1=PE1+KE1;
            E2=PE2+KE2;
            j=1;

            %for system2
            m11=app.Massm1kgEditField_5.Value;
            m22=app.Massm2kgEditField_5.Value;
            l11=app.LenghtL1mEditField_5.Value;
            l22=app.LenghtL2mEditField_5.Value;
            gg=app.Gravititatinalaccgms2EditField_5.Value;

            initial_vaariables2=[m11;m22;l11;l22;gg];

            theta11=app.Theta1radEditField_5.Value;
            theta22=app.Theta2radEditField_5.Value;
            d_theta11=app.Omega1radsEditField_5.Value;
            d_theta22=app.Omega2radsEditField_5.Value;

            initial_conditions2=[theta11;theta22;d_theta11;d_theta22];

            timespan2=0:0.03:app.TimeSlider_2.Value;

            [t2,results2]=ode45(@(t,theta)odefunction(t,theta,initial_conditions2,initial_vaariables2),timespan2,initial_conditions2);


            a2=results2(:,1);
            b2=results2(:,2);

            x11=l11.*sin(a2);
            y11=-l11.*cos(a2);
            x22=l11.*sin(a2)+l22.*sin(b2);
            y22=-l11.*cos(a2)-l22.*cos(b2);
            omega11=results2(:,3);
            omega22=results2(:,4);

            PE11=(m11.*g.*y11);
            KE11=0.5.*m11.*l11.*l11.*omega11.*omega11;
            PE22=(m22.*g.*y22);
            KE22=0.5*m22.*((l11.*l11.*omega11.*omega11)+(l22.*l22.*omega22.*omega22)+(2.*l11.*l22.*omega11.*omega22.*cos(a2-b2)));
            

             for i=1:length(t)
                cla(app.UIAxes6)
                x0=0;
                y0=0; 
                plot(app.UIAxes6,x1(1),y1(1),'ro','MarkerFaceColor','r');
                hold(app.UIAxes6,'on')
                plot(app.UIAxes6,x2(1),y2(1),'go','MarkerFaceColor','g');
                hold(app.UIAxes6,'on')
                plot(app.UIAxes6,[-1 1],[0 0],'color','w')
                hold(app.UIAxes6,'on')
                plot(app.UIAxes6,[x0 x1(i)],[y0 y1(i)],'color','w')
                hold(app.UIAxes6,'on')
                plot(app.UIAxes6,[x1(i) x2(i)],[y1(i) y2(i)],'color','w')
                hold(app.UIAxes6,'on')
                plot(app.UIAxes6,x1(i),y1(i),'ro','MarkerFaceColor','r');
                plot(app.UIAxes6,x2(i),y2(i),'go','MarkerFaceColor','g');
                plot(app.UIAxes6, x1(1:i) , y1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-  -");
                plot(app.UIAxes6,x2(1:i) , y2(1:i) , LineWidth=1 , Color='g' ,  LineStyle="-  -");
                axis(app.UIAxes6,'equal',[-l1-l2 l1+l2 -l1-l2 l1+l2])

                pause(0.001)

                cla(app.UIAxes6_3)
                x0=0;
                y0=0; 
                plot(app.UIAxes6_3,x11(1),y11(1),'ro','MarkerFaceColor','r');
                hold(app.UIAxes6_3,'on')
                plot(app.UIAxes6_3,x22(1),y22(1),'go','MarkerFaceColor','g');
                hold(app.UIAxes6_3,'on')
                plot(app.UIAxes6_3,[-1 1],[0 0],'color','w')
                hold(app.UIAxes6_3,'on')
                plot(app.UIAxes6_3,[x0 x11(i)],[y0 y11(i)],'color','w')
                hold(app.UIAxes6_3,'on')
                plot(app.UIAxes6_3,[x11(i) x22(i)],[y11(i) y22(i)],'color','w')
                hold(app.UIAxes6_3,'on')
                plot(app.UIAxes6_3,x11(i),y11(i),'ro','MarkerFaceColor','r');
                plot(app.UIAxes6_3,x22(i),y22(i),'go','MarkerFaceColor','g');
                plot(app.UIAxes6_3, x11(1:i) , y11(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-  -");
                plot(app.UIAxes6_3,x22(1:i) , y22(1:i) , LineWidth=1 , Color='g' ,  LineStyle="-  -");
                axis(app.UIAxes6_3,'equal',[-l11-l22 l11+l22 -l11-l22 l11+l22])
             end

        end

        % Button pushed function: CompareAngularVelocityButton
        function CompareAngularVelocityButtonPushed(app, event)
            m1=app.Massm1kgEditField_4.Value;
            m2=app.Massm2kgEditField_4.Value;
            l1=app.LenghtL1mEditField_4.Value;
            l2=app.LenghtL2mEditField_4.Value;
            g=app.Gravitationalaccgms2EditField_2.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            theta1=app.Theta1radEditField_4.Value;
            theta2=app.Theta2radEditField_4.Value;
            d_theta1=app.Omega1radsEditField_4.Value;
            d_theta2=app.Omega2radsEditField_4.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider_2.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);


            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);
            omega1=results(:,3);
            omega2=results(:,4);


            %for system2
            m11=app.Massm1kgEditField_5.Value;
            m22=app.Massm2kgEditField_5.Value;
            l11=app.LenghtL1mEditField_5.Value;
            l22=app.LenghtL2mEditField_5.Value;
            gg=app.Gravititatinalaccgms2EditField_5.Value;

            initial_vaariables2=[m11;m22;l11;l22;gg];

            theta11=app.Theta1radEditField_5.Value;
            theta22=app.Theta2radEditField_5.Value;
            d_theta11=app.Omega1radsEditField_5.Value;
            d_theta22=app.Omega2radsEditField_5.Value;

            initial_conditions2=[theta11;theta22;d_theta11;d_theta22];

            timespan2=0:0.03:app.TimeSlider_2.Value;

            [t2,results2]=ode45(@(t,theta)odefunction(t,theta,initial_conditions2,initial_vaariables2),timespan2,initial_conditions2);


            a2=results2(:,1);
            b2=results2(:,2);

            x11=l11.*sin(a2);
            y11=-l11.*cos(a2);
            x22=l11.*sin(a2)+l22.*sin(b2);
            y22=-l11.*cos(a2)-l22.*cos(b2);
            omega11=results2(:,3);
            omega22=results2(:,4);

            max_omega1=max(omega1);
            max_omega2=max(omega2);
            min_omega1=min(omega1);
            min_omega2=min(omega2);
            if(max_omega1>=max_omega2)
                max_omega2=max_omega1;
            end
            if(min_omega1<=min_omega2)
                min_omega2=min_omega1;
            end

            max_omega11=max(omega11);
            max_omega22=max(omega22);
            min_omega11=min(omega11);
            min_omega22=min(omega22);
            if(max_omega11>=max_omega22)
                max_omega22=max_omega11;
            end
            if(min_omega11<=min_omega22)
                min_omega22=min_omega11;
            end
            

            for i=1:length(t2)
                cla(app.UIAxes8)
                plot(app.UIAxes8,t(i),omega1(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes8,'on')
                plot(app.UIAxes8,t(i),omega2(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes8,'on')
                plot(app.UIAxes8,t(1:i) , omega1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes8,'on')
                plot(app.UIAxes8,t(1:i) , omega2(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes8,'on')
                axis(app.UIAxes8,[0 app.TimeSlider_2.Value min_omega2-3 max_omega2+3])
                pause(0.0001)

                cla(app.UIAxes6_6)
                plot(app.UIAxes6_6,t2(i),omega11(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes6_6,'on')
                plot(app.UIAxes6_6,t2(i),omega22(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes6_6,'on')
                plot(app.UIAxes6_6,t2(1:i) , omega11(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes6_6,'on')
                plot(app.UIAxes6_6,t2(1:i) , omega22(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes6_6,'on')
                axis(app.UIAxes6_6,[0 app.TimeSlider_2.Value min_omega22-3 max_omega22+3])
                pause(0.0001)
            end
        end

        % Button pushed function: CompareEnergyButton
        function CompareEnergyButtonPushed(app, event)
            app.TimeSlider_2.Value=app.TimeSlider_2.Value;
            m1=app.Massm1kgEditField_4.Value;
            m2=app.Massm2kgEditField_4.Value;
            l1=app.LenghtL1mEditField_4.Value;
            l2=app.LenghtL2mEditField_4.Value;
            g=app.Gravitationalaccgms2EditField_2.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            theta1=app.Theta1radEditField_4.Value;
            theta2=app.Theta2radEditField_4.Value;
            d_theta1=app.Omega1radsEditField_4.Value;
            d_theta2=app.Omega2radsEditField_4.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider_2.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);


            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);
            omega1=results(:,3);
            omega2=results(:,4);

            PE1=(m1.*g.*y1);
            KE1=0.5.*m1.*l1.*l1.*omega1.*omega1;
            PE2=(m2.*g.*y2);
            KE2=0.5*m2.*((l1.*l1.*omega1.*omega1)+(l2.*l2.*omega2.*omega2)+(2.*l1.*l2.*omega1.*omega2.*cos(a-b)));
            
            E1=PE1+KE1;
            E2=PE2+KE2;
            j=1;

            %for system2
            m11=app.Massm1kgEditField_5.Value;
            m22=app.Massm2kgEditField_5.Value;
            l11=app.LenghtL1mEditField_5.Value;
            l22=app.LenghtL2mEditField_5.Value;
            gg=app.Gravititatinalaccgms2EditField_5.Value;

            initial_vaariables2=[m11;m22;l11;l22;gg];

            theta11=app.Theta1radEditField_5.Value;
            theta22=app.Theta2radEditField_5.Value;
            d_theta11=app.Omega1radsEditField_5.Value;
            d_theta22=app.Omega2radsEditField_5.Value;

            initial_conditions2=[theta11;theta22;d_theta11;d_theta22];

            timespan2=0:0.03:app.TimeSlider_2.Value;

            [t2,results2]=ode45(@(t,theta)odefunction(t,theta,initial_conditions2,initial_vaariables2),timespan2,initial_conditions2);


            a2=results2(:,1);
            b2=results2(:,2);

            x11=l11.*sin(a2);
            y11=-l11.*cos(a2);
            x22=l11.*sin(a2)+l22.*sin(b2);
            y22=-l11.*cos(a2)-l22.*cos(b2);
            omega11=results2(:,3);
            omega22=results2(:,4);

            PE11=(m11.*g.*y11);
            KE11=0.5.*m11.*l11.*l11.*omega11.*omega11;
            PE22=(m22.*g.*y22);
            KE22=0.5*m22.*((l11.*l11.*omega11.*omega11)+(l22.*l22.*omega22.*omega22)+(2.*l11.*l22.*omega11.*omega22.*cos(a2-b2)));
            
            E11=PE11+KE11;
            E22=PE22+KE22;

            max_E1=max(E1);
            max_E2=max(E2);
            min_E1=min(E1);
            min_E2=min(E2);
            if(max_E1>=max_E2)
                max_E2=max_E1;
            end
            if(min_E1<=min_E2)
                min_E2=min_E1;
            end

            max_E11=max(E11);
            max_E22=max(E22);
            min_E11=min(E11);
            min_E22=min(E22);
            if(max_E11>=max_E22)
                max_E22=max_E11;
            end
            if(min_E11<=min_E22)
                min_E22=min_E11;
            end

            for i=1:length(t2)
                cla(app.UIAxes10)
                plot(app.UIAxes10,t(i),E1(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes10,'on')
                plot(app.UIAxes10,t(i),E2(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes10,'on')
                plot(app.UIAxes10,t(1:i) , E1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes10,'on')
                plot(app.UIAxes10,t(1:i) , E2(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes10,'on')
                axis(app.UIAxes10,[0 app.TimeSlider_2.Value min_E2-3 max_E2+3])
                pause(0.0001)

                cla(app.UIAxes6_7)
                plot(app.UIAxes6_7,t2(i),E11(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes6_7,'on')
                plot(app.UIAxes6_7,t2(i),E22(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes6_7,'on')
                plot(app.UIAxes6_7,t2(1:i) , E11(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes6_7,'on')
                plot(app.UIAxes6_7,t2(1:i) , E22(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes6_7,'on')
                axis(app.UIAxes6_7,[0 app.TimeSlider_2.Value min_E22-3 max_E22+3])
                pause(0.0001)


             end
        end

        % Button pushed function: CompareTrajectoryButton
        function CompareTrajectoryButtonPushed(app, event)
            m1=app.Massm1kgEditField_4.Value;
            m2=app.Massm2kgEditField_4.Value;
            l1=app.LenghtL1mEditField_4.Value;
            l2=app.LenghtL2mEditField_4.Value;
            g=app.Gravitationalaccgms2EditField_2.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            theta1=app.Theta1radEditField_4.Value;
            theta2=app.Theta2radEditField_4.Value;
            d_theta1=app.Omega1radsEditField_4.Value;
            d_theta2=app.Omega2radsEditField_4.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider_2.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);


            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);        

            %for system2
            m11=app.Massm1kgEditField_5.Value;
            m22=app.Massm2kgEditField_5.Value;
            l11=app.LenghtL1mEditField_5.Value;
            l22=app.LenghtL2mEditField_5.Value;
            gg=app.Gravititatinalaccgms2EditField_5.Value;

            initial_vaariables2=[m11;m22;l11;l22;gg];

            theta11=app.Theta1radEditField_5.Value;
            theta22=app.Theta2radEditField_5.Value;
            d_theta11=app.Omega1radsEditField_5.Value;
            d_theta22=app.Omega2radsEditField_5.Value;

            initial_conditions2=[theta11;theta22;d_theta11;d_theta22];

            timespan2=0:0.03:app.TimeSlider_2.Value;

            [t2,results2]=ode45(@(t,theta)odefunction(t,theta,initial_conditions2,initial_vaariables2),timespan2,initial_conditions2);


            a2=results2(:,1);
            b2=results2(:,2);

            x11=l11.*sin(a2);
            y11=-l11.*cos(a2);
            x22=l11.*sin(a2)+l22.*sin(b2);
            y22=-l11.*cos(a2)-l22.*cos(b2);   
 

                    x0=0;
                    y0=0;
                    cla(app.UIAxes6_8)
                    plot(app.UIAxes6_8,x1(1),y1(1),'ro','MarkerFaceColor','r');
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,x2(1),y2(1),'go','MarkerFaceColor','g');
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,0,0,'w.')
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,[-1 1],[0 0],'color','w')
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,[x0 x1(end)],[y0 y1(end)],'color','w')
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,[x1(end) x2(end)],[y1(end) y2(end)],'color','w')
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8, x1(1:end) , y1(1:end) , LineWidth=1 , Color='r' ,  LineStyle="-  -");
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,x2(1:end) , y2(1:end) , LineWidth=1 , Color='g',  LineStyle="-  -");
                    axis(app.UIAxes6_8,'equal',[-l1-l2 l1+l2 -l1-l2 l1+l2])
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,x1(end),y1(end),'ro','MarkerFaceColor','r');
                    hold(app.UIAxes6_8,'on')
                    plot(app.UIAxes6_8,x2(end),y2(end),'go','MarkerFaceColor','g');
                    pause(0.0001)

                    cla(app.UIAxes6_9)
                    plot(app.UIAxes6_9,x11(1),y11(1),'ro','MarkerFaceColor','r');
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,x22(1),y22(1),'go','MarkerFaceColor','g');
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,0,0,'w.')
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,[-1 1],[0 0],'color','w')
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,[x0 x11(end)],[y0 y11(end)],'color','w')
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,[x11(end) x22(end)],[y11(end) y22(end)],'color','w')
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9, x11(1:end) , y11(1:end) , LineWidth=1 , Color='r' ,  LineStyle="-  -");
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,x22(1:end) , y22(1:end) , LineWidth=1 , Color='g',  LineStyle="-  -");
                    axis(app.UIAxes6_9,'equal',[-l11-l22 l11+l22 -l11-l22 l11+l22])
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,x11(end),y11(end),'ro','MarkerFaceColor','r');
                    hold(app.UIAxes6_9,'on')
                    plot(app.UIAxes6_9,x22(end),y22(end),'go','MarkerFaceColor','g');
                    pause(0.0001)


        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ComparisonAnalysis2Tab;
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.TabGroup.SelectedTab=app.HomeTab;
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.AnalysisTab;

        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.CompareTab;

        end

        % Button pushed function: BackButton_6
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ComparisonAnalysisTab;

        end

        % Button pushed function: ShowAngularPositionButton
        function ShowAngularPositionButtonPushed(app, event)
            m1=app.Massm1kgEditField.Value;
            m2=app.Massm2kgEditField.Value;
            l1=app.LenghtL1mEditField.Value;
            l2=app.LenghtL2mEditField.Value;
            g=app.Gravitationalaccgms2EditField.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            % intial_conditions

            theta1=app.Theta1radEditField.Value;
            theta2=app.Theta2radEditField.Value;
            d_theta1=app.Omega1radsEditField.Value;
            d_theta2=app.Omega2radsEditField.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);

            theta1=results(:,1);
            theta2=results(:,2);
            max_Theta1=max(theta1);
            max_Theta2=max(theta2);
            min_Theta1=min(theta1);
            min_Theta2=min(theta2);
            if(max_Theta1>=max_Theta2)
                max_Theta2=max_Theta1;
            end
            if(min_Theta1<=min_Theta2)
                min_Theta2=min_Theta1;
            end

             for i=1:length(t)
                cla(app.UIAxes3_2)
                plot(app.UIAxes3_2,t(i),theta1(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes3_2,'on')
                plot(app.UIAxes3_2,t(i),theta2(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes3_2,'on')
                plot(app.UIAxes3_2,t(1:i) , theta1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes3_2,'on')
                plot(app.UIAxes3_2,t(1:i) , theta2(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes3_2,'on')
                axis(app.UIAxes3_2,[0 app.TimeSlider.Value,min_Theta2-3 max_Theta2+3 ])
                pause(0.0001)
             end
        end

        % Button pushed function: NextTabButton_2
        function NextTabButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.CompareTab;
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.TabGroup.SelectedTab=app.AnalysisTab;
        end

        % Button pushed function: CompareAngularPositionButton
        function CompareAngularPositionButtonPushed(app, event)
            app.TimeSlider_2.Value=app.TimeSlider_2.Value;
            m1=app.Massm1kgEditField_4.Value;
            m2=app.Massm2kgEditField_4.Value;
            l1=app.LenghtL1mEditField_4.Value;
            l2=app.LenghtL2mEditField_4.Value;
            g=app.Gravitationalaccgms2EditField_2.Value;

            initial_vaariables=[m1;m2;l1;l2;g];

            theta1=app.Theta1radEditField_4.Value;
            theta2=app.Theta2radEditField_4.Value;
            d_theta1=app.Omega1radsEditField_4.Value;
            d_theta2=app.Omega2radsEditField_4.Value;

            initial_conditions=[theta1;theta2;d_theta1;d_theta2];

            timespan=0:0.03:app.TimeSlider_2.Value;

            [t,results]=ode45(@(t,theta)odefunction(t,theta,initial_conditions,initial_vaariables),timespan,initial_conditions);


            a=results(:,1);
            b=results(:,2);

            x1=l1.*sin(a);
            y1=-l1.*cos(a);
            x2=l1.*sin(a)+l2.*sin(b);
            y2=-l1.*cos(a)-l2.*cos(b);
            
            Theta1=results(:,1);
            Theta2=results(:,2);

            %for system2
            m11=app.Massm1kgEditField_5.Value;
            m22=app.Massm2kgEditField_5.Value;
            l11=app.LenghtL1mEditField_5.Value;
            l22=app.LenghtL2mEditField_5.Value;
            gg=app.Gravititatinalaccgms2EditField_5.Value;

            initial_vaariables2=[m11;m22;l11;l22;gg];

            theta11=app.Theta1radEditField_5.Value;
            theta22=app.Theta2radEditField_5.Value;
            d_theta11=app.Omega1radsEditField_5.Value;
            d_theta22=app.Omega2radsEditField_5.Value;

            initial_conditions2=[theta11;theta22;d_theta11;d_theta22];

            timespan2=0:0.03:app.TimeSlider_2.Value;

            [t2,results2]=ode45(@(t,theta)odefunction(t,theta,initial_conditions2,initial_vaariables2),timespan2,initial_conditions2);


            a2=results2(:,1);
            b2=results2(:,2);

            x11=l11.*sin(a2);
            y11=-l11.*cos(a2);
            x22=l11.*sin(a2)+l22.*sin(b2);
            y22=-l11.*cos(a2)-l22.*cos(b2);

            Theta11=results2(:,1);
            Theta22=results2(:,2);


            max_Theta1=max(Theta1);
            max_Theta2=max(Theta2);
            min_Theta1=min(Theta1);
            min_Theta2=min(Theta2);
            if(max_Theta1>=max_Theta2)
                max_Theta2=max_Theta1;
            end
            if(min_Theta1<=min_Theta2)
                min_Theta2=min_Theta1;
            end

            max_Theta11=max(Theta11);
            max_Theta22=max(Theta22);
            min_Theta11=min(Theta11);
            min_Theta22=min(Theta22);
            if(max_Theta11>=max_Theta22)
                max_Theta22=max_Theta11;
            end
            if(min_Theta11<=min_Theta22)
                min_Theta22=min_Theta11;
            end

            for i=1:length(t2)
                cla(app.UIAxes10_2)
                plot(app.UIAxes10_2,t(i),Theta1(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes10_2,'on')
                plot(app.UIAxes10_2,t(i),Theta2(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes10_2,'on')
                plot(app.UIAxes10_2,t(1:i) , Theta1(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes10_2,'on')
                plot(app.UIAxes10_2,t(1:i) , Theta2(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes10_2,'on')
                axis(app.UIAxes10_2,[0 app.TimeSlider_2.Value min_Theta2-3 max_Theta2+3])
                pause(0.0001)

                cla(app.UIAxes6_10)
                plot(app.UIAxes6_10,t2(i),Theta11(i),'ro','MarkerFaceColor','r');
                hold(app.UIAxes6_10,'on')
                plot(app.UIAxes6_10,t2(i),Theta22(i),'go','MarkerFaceColor','g');
                hold(app.UIAxes6_10,'on')
                plot(app.UIAxes6_10,t2(1:i) , Theta11(1:i) , LineWidth=1 , Color='r' ,  LineStyle="-");
                hold(app.UIAxes6_10,'on')
                plot(app.UIAxes6_10,t2(1:i) , Theta22(1:i) , LineWidth=1 , Color='g',  LineStyle="-");
                hold(app.UIAxes6_10,'on')
                axis(app.UIAxes6_10,[0 app.TimeSlider_2.Value min_Theta22-3 max_Theta22+3])
                pause(0.0001)


            end

        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.ComparisonAnalysis3Tab;
        end

        % Button pushed function: BackButton_7
        function BackButton_7Pushed(app, event)
            app.TabGroup.SelectedTab=app.ComparisonAnalysis2Tab;
        end

        % Button pushed function: LetsStartButton
        function LetsStartButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.HomeTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroductionTab = uitab(app.TabGroup);
            app.IntroductionTab.Title = 'Introduction';
            app.EditField_5 = uieditfield(app.IntroductionTab, 'text');
            app.EditField_5.Value = 'Double Pendulum Motion Simulator';
            app.Image2 = uiimage(app.IntroductionTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-02-28 at 7.48.41 PM.jpeg');
            app.LetsStartButton = uibutton(app.IntroductionTab, 'push');
            app.LetsStartButton.ButtonPushedFcn = createCallbackFcn(app, @LetsStartButtonPushed, true);
            app.LetsStartButton.Text = 'Let''s Start!';
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.EditField = uieditfield(app.HomeTab, 'text');
            app.EditField.Value = '  Double Pendulum Motion Simulator';
            app.EditField_2 = uieditfield(app.HomeTab, 'text');
            app.EditField_2.Value = '   Welcome! Please Input the Following Paramaters';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Dp.PNG');
            app.StartAnalysisButton = uibutton(app.HomeTab, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.LenghtL1mEditFieldLabel = uilabel(app.HomeTab);
            app.LenghtL1mEditFieldLabel.Text = 'Lenght,L1(m)';
            app.LenghtL1mEditField = uieditfield(app.HomeTab, 'numeric');
            app.LenghtL1mEditField.Value = 1;
            app.LenghtL2mEditFieldLabel = uilabel(app.HomeTab);
            app.LenghtL2mEditFieldLabel.Text = 'Lenght,L2(m)';
            app.LenghtL2mEditField = uieditfield(app.HomeTab, 'numeric');
            app.LenghtL2mEditField.Value = 2;
            app.Massm2kgEditFieldLabel = uilabel(app.HomeTab);
            app.Massm2kgEditFieldLabel.Text = 'Mass,m2(kg)';
            app.Massm2kgEditField = uieditfield(app.HomeTab, 'numeric');
            app.Massm2kgEditField.Value = 1;
            app.Theta1radEditFieldLabel = uilabel(app.HomeTab);
            app.Theta1radEditFieldLabel.Text = 'Theta,ϴ1(rad)';
            app.Theta1radEditField = uieditfield(app.HomeTab, 'numeric');
            app.Theta1radEditField.Value = 2.2;
            app.Theta2radEditFieldLabel = uilabel(app.HomeTab);
            app.Theta2radEditFieldLabel.Text = 'Theta,ϴ2(rad)';
            app.Theta2radEditField = uieditfield(app.HomeTab, 'numeric');
            app.Theta2radEditField.Value = 1.6;
            app.Omega1radsEditFieldLabel = uilabel(app.HomeTab);
            app.Omega1radsEditFieldLabel.Text = 'Omega,ω1(rad/s)';
            app.Omega1radsEditField = uieditfield(app.HomeTab, 'numeric');
            app.Omega2radsEditField = uieditfield(app.HomeTab, 'numeric');
            app.Omega2radsEditFieldLabel = uilabel(app.HomeTab);
            app.Omega2radsEditFieldLabel.Text = 'Omega,ω2(rad/s)';
            app.Massm1kgEditFieldLabel = uilabel(app.HomeTab);
            app.Massm1kgEditFieldLabel.Text = 'Mass,m1(kg)';
            app.Massm1kgEditField = uieditfield(app.HomeTab, 'numeric');
            app.Massm1kgEditField.Value = 2;
            app.Gravitationalaccgms2EditFieldLabel = uilabel(app.HomeTab);
            app.Gravitationalaccgms2EditFieldLabel.Text = 'Gravitational acc,g(m/s^2)';
            app.Gravitationalaccgms2EditField = uieditfield(app.HomeTab, 'numeric');
            app.Gravitationalaccgms2EditField.Value = 9.8;
            app.TimeSliderLabel = uilabel(app.HomeTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.HomeTab);
            app.TimeSlider.Limits = [0 40];
            app.TimeSlider.Value = 8;
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.SizeChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.ButtonDownFcn = createCallbackFcn(app, @AnalysisTabButtonDown, true);
            app.UIAxes2 = uiaxes(app.AnalysisTab);
            title(app.UIAxes2, 'Angular Velocity Vs Time')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Angular Velocity')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes5 = uiaxes(app.AnalysisTab);
            title(app.UIAxes5, 'Y-Position Vs X-Position')
            xlabel(app.UIAxes5, 'X-Position')
            ylabel(app.UIAxes5, 'Y-Position')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.AnalysisTab);
            title(app.UIAxes4, 'Energy vs Time')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Energy')
            zlabel(app.UIAxes4, 'Z')
            app.ShowPositionButton = uibutton(app.AnalysisTab, 'push');
            app.ShowPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPositionButtonPushed, true);
            app.ShowPositionButton.Text = 'Show Position';
            app.ShowAngularVelocityButton = uibutton(app.AnalysisTab, 'push');
            app.ShowAngularVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAngularVelocityButtonPushed, true);
            app.ShowAngularVelocityButton.Text = 'Show Angular Velocity';
            app.NextTabButton = uibutton(app.AnalysisTab, 'push');
            app.NextTabButton.ButtonPushedFcn = createCallbackFcn(app, @NextTabButtonPushed, true);
            app.NextTabButton.Text = 'Next Tab';
            app.BackButton_4 = uibutton(app.AnalysisTab, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.ShowEnergyButton = uibutton(app.AnalysisTab, 'push');
            app.ShowEnergyButton.ButtonPushedFcn = createCallbackFcn(app, @ShowEnergyButtonPushed, true);
            app.ShowEnergyButton.Text = 'Show Energy';
            app.Analysis2Tab = uitab(app.TabGroup);
            app.Analysis2Tab.Title = 'Analysis2';
            app.UIAxes3 = uiaxes(app.Analysis2Tab);
            title(app.UIAxes3, 'Complete Tajectory')
            xlabel(app.UIAxes3, 'X-Position')
            ylabel(app.UIAxes3, 'Y-Position')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.Analysis2Tab);
            title(app.UIAxes3_2, 'Angular Position vs Time')
            xlabel(app.UIAxes3_2, 'Time')
            ylabel(app.UIAxes3_2, 'Angular Position')
            zlabel(app.UIAxes3_2, 'Z')
            app.ShowCompleteTrajectoryButton = uibutton(app.Analysis2Tab, 'push');
            app.ShowCompleteTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCompleteTrajectoryButtonPushed, true);
            app.ShowCompleteTrajectoryButton.Text = 'Show Complete Trajectory';
            app.ShowAngularPositionButton = uibutton(app.Analysis2Tab, 'push');
            app.ShowAngularPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAngularPositionButtonPushed, true);
            app.ShowAngularPositionButton.Text = 'Show Angular Position';
            app.NextTabButton_2 = uibutton(app.Analysis2Tab, 'push');
            app.NextTabButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextTabButton_2Pushed, true);
            app.NextTabButton_2.Text = 'Next Tab';
            app.BackButton_5 = uibutton(app.Analysis2Tab, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            app.CompareTab = uitab(app.TabGroup);
            app.CompareTab.Title = 'Compare';
            app.DuplicateCurrentSysteminSystem1Button = uibutton(app.CompareTab, 'push');
            app.DuplicateCurrentSysteminSystem1Button.ButtonPushedFcn = createCallbackFcn(app, @DuplicateCurrentSysteminSystem1ButtonPushed, true);
            app.DuplicateCurrentSysteminSystem1Button.Text = 'Duplicate Current System in System 1';
            app.CompareButton = uibutton(app.CompareTab, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.EditField_3 = uieditfield(app.CompareTab, 'text');
            app.EditField_3.Value = 'System-1';
            app.Massm1kgEditField_4Label = uilabel(app.CompareTab);
            app.Massm1kgEditField_4Label.Text = 'Mass,m1(kg)';
            app.Massm1kgEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.LenghtL1mEditField_4Label = uilabel(app.CompareTab);
            app.LenghtL1mEditField_4Label.Text = 'Lenght,L1(m)';
            app.LenghtL1mEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.Theta1radEditField_4Label = uilabel(app.CompareTab);
            app.Theta1radEditField_4Label.Text = 'Theta,ϴ1(rad)';
            app.Theta1radEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.Omega1radsEditField_4Label = uilabel(app.CompareTab);
            app.Omega1radsEditField_4Label.Text = 'Omega,ω1(rad/s)';
            app.Omega1radsEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.Massm2kgEditField_4Label = uilabel(app.CompareTab);
            app.Massm2kgEditField_4Label.Text = 'Mass,m2(kg)';
            app.Massm2kgEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.LenghtL2mEditField_4Label = uilabel(app.CompareTab);
            app.LenghtL2mEditField_4Label.Text = 'Lenght,L2(m)';
            app.LenghtL2mEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.Theta2radEditField_4Label = uilabel(app.CompareTab);
            app.Theta2radEditField_4Label.Text = 'Theta,ϴ2(rad)';
            app.Theta2radEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.Omega2radsEditField_4Label = uilabel(app.CompareTab);
            app.Omega2radsEditField_4Label.Text = 'Omega,ω2(rad/s)';
            app.Omega2radsEditField_4 = uieditfield(app.CompareTab, 'numeric');
            app.Gravitationalaccgms2EditField_2Label = uilabel(app.CompareTab);
            app.Gravitationalaccgms2EditField_2Label.Text = 'Gravitational acc,g(m/s^2)';
            app.Gravitationalaccgms2EditField_2 = uieditfield(app.CompareTab, 'numeric');
            app.TimeSlider_2Label = uilabel(app.CompareTab);
            app.TimeSlider_2Label.Text = 'Time';
            app.TimeSlider_2 = uislider(app.CompareTab);
            app.Massm1kgEditField_5Label = uilabel(app.CompareTab);
            app.Massm1kgEditField_5Label.Text = 'Mass,m1(kg)';
            app.Massm1kgEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.Massm1kgEditField_5.Value = 2;
            app.LenghtL1mEditField_5Label = uilabel(app.CompareTab);
            app.LenghtL1mEditField_5Label.Text = 'Lenght,L1(m)';
            app.LenghtL1mEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.LenghtL1mEditField_5.Value = 1;
            app.Theta1radEditField_5Label = uilabel(app.CompareTab);
            app.Theta1radEditField_5Label.Text = 'Theta,ϴ1(rad)';
            app.Theta1radEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.Theta1radEditField_5.Value = 2;
            app.Omega1radsEditField_5Label = uilabel(app.CompareTab);
            app.Omega1radsEditField_5Label.Text = 'Omega,ω1(rad/s)';
            app.Omega1radsEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.Massm2kgEditField_5Label = uilabel(app.CompareTab);
            app.Massm2kgEditField_5Label.Text = 'Mass,m2(kg)';
            app.Massm2kgEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.Massm2kgEditField_5.Value = 1;
            app.LenghtL2mEditField_5Label = uilabel(app.CompareTab);
            app.LenghtL2mEditField_5Label.Text = 'Lenght,L2(m)';
            app.LenghtL2mEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.LenghtL2mEditField_5.Value = 2;
            app.Theta2radEditField_5Label = uilabel(app.CompareTab);
            app.Theta2radEditField_5Label.Text = 'Theta,ϴ2(rad)';
            app.Theta2radEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.Theta2radEditField_5.Value = 1.6;
            app.Omega2radsEditField_5Label = uilabel(app.CompareTab);
            app.Omega2radsEditField_5Label.Text = 'Omega,ω2(rad/s)';
            app.Omega2radsEditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.Gravititatinalaccgms2EditField_5Label = uilabel(app.CompareTab);
            app.Gravititatinalaccgms2EditField_5Label.Text = 'Gravititatinal acc,g(m/s^2)';
            app.Gravititatinalaccgms2EditField_5 = uieditfield(app.CompareTab, 'numeric');
            app.Gravititatinalaccgms2EditField_5.Value = 9.8;
            app.EditField_4 = uieditfield(app.CompareTab, 'text');
            app.EditField_4.Value = 'System-2';
            app.BackButton_3 = uibutton(app.CompareTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.ComparisonAnalysisTab = uitab(app.TabGroup);
            app.ComparisonAnalysisTab.Title = 'Comparison Analysis';
            app.UIAxes6 = uiaxes(app.ComparisonAnalysisTab);
            title(app.UIAxes6, 'Y-Position Vs X-Position( System1)')
            xlabel(app.UIAxes6, 'X-Position')
            ylabel(app.UIAxes6, 'Y-Position')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes8 = uiaxes(app.ComparisonAnalysisTab);
            title(app.UIAxes8, 'Angular Velocity Vs Time (System1)')
            xlabel(app.UIAxes8, 'Time')
            ylabel(app.UIAxes8, 'Angular Velocity')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes6_3 = uiaxes(app.ComparisonAnalysisTab);
            title(app.UIAxes6_3, 'Y-Position Vs X-Position( System2)')
            xlabel(app.UIAxes6_3, 'X-Position')
            ylabel(app.UIAxes6_3, 'Y-Position')
            zlabel(app.UIAxes6_3, 'Z')
            app.UIAxes6_6 = uiaxes(app.ComparisonAnalysisTab);
            title(app.UIAxes6_6, 'Angular Velocity Vs Time (System2)')
            xlabel(app.UIAxes6_6, 'Time')
            ylabel(app.UIAxes6_6, 'Angular Velocity')
            zlabel(app.UIAxes6_6, 'Z')
            app.ComparePostionButton = uibutton(app.ComparisonAnalysisTab, 'push');
            app.ComparePostionButton.ButtonPushedFcn = createCallbackFcn(app, @ComparePostionButtonPushed, true);
            app.ComparePostionButton.Text = 'Compare Postion';
            app.CompareAngularVelocityButton = uibutton(app.ComparisonAnalysisTab, 'push');
            app.CompareAngularVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAngularVelocityButtonPushed, true);
            app.CompareAngularVelocityButton.Text = 'Compare Angular Velocity';
            app.NextButton = uibutton(app.ComparisonAnalysisTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton_2 = uibutton(app.ComparisonAnalysisTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.ComparisonAnalysis2Tab = uitab(app.TabGroup);
            app.ComparisonAnalysis2Tab.Title = 'Comparison Analysis2';
            app.UIAxes10 = uiaxes(app.ComparisonAnalysis2Tab);
            title(app.UIAxes10, 'Energy Vs Time (System1)')
            xlabel(app.UIAxes10, 'Time')
            ylabel(app.UIAxes10, 'Energy')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes6_7 = uiaxes(app.ComparisonAnalysis2Tab);
            title(app.UIAxes6_7, 'Energy Vs Time (System2)')
            xlabel(app.UIAxes6_7, 'Time')
            ylabel(app.UIAxes6_7, 'Energy')
            zlabel(app.UIAxes6_7, 'Z')
            app.UIAxes10_2 = uiaxes(app.ComparisonAnalysis2Tab);
            title(app.UIAxes10_2, 'Angular Position Vs Time (System1)')
            xlabel(app.UIAxes10_2, 'Time')
            ylabel(app.UIAxes10_2, 'Angular Position')
            zlabel(app.UIAxes10_2, 'Z')
            app.UIAxes6_10 = uiaxes(app.ComparisonAnalysis2Tab);
            title(app.UIAxes6_10, 'Angular Position Vs Time (System2)')
            xlabel(app.UIAxes6_10, 'Time')
            ylabel(app.UIAxes6_10, 'Angular Position')
            zlabel(app.UIAxes6_10, 'Z')
            app.CompareEnergyButton = uibutton(app.ComparisonAnalysis2Tab, 'push');
            app.CompareEnergyButton.ButtonPushedFcn = createCallbackFcn(app, @CompareEnergyButtonPushed, true);
            app.CompareEnergyButton.Text = 'Compare Energy';
            app.CompareAngularPositionButton = uibutton(app.ComparisonAnalysis2Tab, 'push');
            app.CompareAngularPositionButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAngularPositionButtonPushed, true);
            app.CompareAngularPositionButton.Text = 'Compare Angular Position';
            app.NextButton_2 = uibutton(app.ComparisonAnalysis2Tab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.BackButton_7 = uibutton(app.ComparisonAnalysis2Tab, 'push');
            app.BackButton_7.ButtonPushedFcn = createCallbackFcn(app, @BackButton_7Pushed, true);
            app.BackButton_7.Text = 'Back';
            app.ComparisonAnalysis3Tab = uitab(app.TabGroup);
            app.ComparisonAnalysis3Tab.Title = 'Comparison Analysis3';
            app.UIAxes6_8 = uiaxes(app.ComparisonAnalysis3Tab);
            title(app.UIAxes6_8, 'Complete Trajectory (System1)')
            xlabel(app.UIAxes6_8, 'X-Position')
            ylabel(app.UIAxes6_8, 'Y-Position')
            zlabel(app.UIAxes6_8, 'Z')
            app.UIAxes6_9 = uiaxes(app.ComparisonAnalysis3Tab);
            title(app.UIAxes6_9, 'Complete Trajectory (System2)')
            xlabel(app.UIAxes6_9, 'X-Position')
            ylabel(app.UIAxes6_9, 'Y-Position')
            zlabel(app.UIAxes6_9, 'Z')
            app.CompareTrajectoryButton = uibutton(app.ComparisonAnalysis3Tab, 'push');
            app.CompareTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CompareTrajectoryButtonPushed, true);
            app.CompareTrajectoryButton.Text = 'Compare Trajectory';
            app.BackButton_6 = uibutton(app.ComparisonAnalysis3Tab, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_6.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Double_pendulum_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end